classdef appPong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PlayButton       matlab.ui.control.Button
        StepSlider       matlab.ui.control.Slider
        StepSliderLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plot_env(app, state)
            % Estrai le variabili di stato
            yBar = state(1);
            xBall = state(2);
            yBall = state(3);
        
            xBar = 1;
            lBar = 1;
            hBar = 5;
            lBall = 1;
            lField = 30;
            hField = 30;
        
            cla(app.UIAxes, "reset");
            app.UIAxes.XLim = [0, 30];
            app.UIAxes.YLim = [0, 30];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];

            % Disegna la barra
            rectangle(app.UIAxes,'Position',[xBar, yBar-hBar/2, lBar, hBar],'FaceColor','b')
        
            % Disegna la palla
            rectangle(app.UIAxes,'Position',[xBall-lBall/2, yBall-lBall/2, 1, lBall],'Curvature',[1 1],'FaceColor','r')


            % Aggiorna la figura
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            load("trainedAgent3.mat", "agent")
            load("env.mat", "env")
            state = env.ResetFcn();
            step = app.StepSlider.Value;
            isDone = false;
            while ~isDone && step>0
               plot_env(app, state)
               action = agent.getAction(state);
               [nextObs, reward, isDone, nextState] = env.StepFcn(action{1}, state);
               state = nextState;
               step = step -1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PONG')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 30];
            app.UIAxes.YLim = [0 30];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StepSliderLabel = uilabel(app.UIFigure);
            app.StepSliderLabel.Text = 'Step';
            app.StepSlider = uislider(app.UIFigure);
            app.StepSlider.Limits = [0 700];
            app.StepSlider.Value = 500;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPong

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end